classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        UIAxes                   matlab.ui.control.UIAxes
        addButton                matlab.ui.control.Button
        Label_2                  matlab.ui.control.Label
        basicATKField            matlab.ui.control.NumericEditField
        Label_3                  matlab.ui.control.Label
        addATKField              matlab.ui.control.NumericEditField
        Label_4                  matlab.ui.control.Label
        amplitudeField           matlab.ui.control.NumericEditField
        Label_5                  matlab.ui.control.Label
        damageAmpField           matlab.ui.control.NumericEditField
        Label_6                  matlab.ui.control.Label
        criChanceField           matlab.ui.control.NumericEditField
        Label_7                  matlab.ui.control.Label
        criDamageField           matlab.ui.control.NumericEditField
        Label_8                  matlab.ui.control.Label
        toughnessField           matlab.ui.control.NumericEditField
        Label_9                  matlab.ui.control.Label
        damageResistField        matlab.ui.control.NumericEditField
        Label_10                 matlab.ui.control.Label
        vulnerableField          matlab.ui.control.NumericEditField
        Label_11                 matlab.ui.control.Label
        penetrateField           matlab.ui.control.NumericEditField
        Label_12                 matlab.ui.control.Label
        debuffChanceField        matlab.ui.control.NumericEditField
        Label_13                 matlab.ui.control.Label
        defenceRedField          matlab.ui.control.NumericEditField
        Label_14                 matlab.ui.control.Label
        pLevelField              matlab.ui.control.NumericEditField
        Label_15                 matlab.ui.control.Label
        eLevelField              matlab.ui.control.NumericEditField
        Label_16                 matlab.ui.control.Label
        attributeSelectDropDown  matlab.ui.control.DropDown
        Label_17                 matlab.ui.control.Label
        graphList                matlab.ui.control.ListBox
        deleteBtn                matlab.ui.control.Button
        basicAttributesLabel     matlab.ui.control.Label
        commentLabel             matlab.ui.control.Label
        warningLabel             matlab.ui.control.Label
        calButton                matlab.ui.control.Button
        Label_18                 matlab.ui.control.Label
        criResultField           matlab.ui.control.EditField
        Label_19                 matlab.ui.control.Label
        NcriResultField          matlab.ui.control.EditField
        Label_20                 matlab.ui.control.Label
        estResultField           matlab.ui.control.EditField
        clearBtn                 matlab.ui.control.Button
        Label_21                 matlab.ui.control.Label
        Switch                   matlab.ui.control.Switch
    end


    properties (Access = private)
        basicATK = 1016; %基础白值
        attackAmp = 0; %攻击增幅
        addATK = 2337; %额外白值
        amplitude = 0.27+0.63; %技能倍率
        damageAmp = 0.1; %增伤
        criChance = 0.78; %暴击率
        criDamage = 1.072; %暴击伤害
        toughness = 0.1; %韧性
        damageResist = 0.2; %属性抗性
        vulnerable = 0; %易伤
        penetrate = 0; %穿透
        debuffChance = 0; %debuff最终命中
        defenceRed = 0.1; %减防
        pLevel = 70; %我方等级
        eLevel = 63; %敌方等级
        
        plots = containers.Map;
    end

    methods (Access = public)
    
        function damageE = damageEstimation(app)
            damageE = (app.basicATK*(1+app.attackAmp)+app.addATK)...
                *app.amplitude*(1+app.damageAmp)...
                *(1+app.criChance*app.criDamage)...
                *(1-app.toughness)*((1+app.vulnerable)...
                *(1-app.damageResist+app.penetrate))...
                *(1+(1-app.debuffChance*app.defenceRed)...
                    *((app.eLevel*10+200)./(app.pLevel*10+200))).^-1;
        end
    
        function app = getAttributes(app)
            app.basicATK = app.basicATKField.Value;
            app.attackAmp = 0;
            app.addATK = app.addATKField.Value;
            app.amplitude = app.amplitudeField.Value;
            app.damageAmp = app.damageAmpField.Value;
            app.criChance = app.criChanceField.Value;
            app.criDamage = app.criDamageField.Value;
            app.toughness = app.toughnessField.Value;
            app.damageResist = app.damageResistField.Value;
            app.vulnerable = app.vulnerableField.Value;
            app.penetrate = app.penetrateField.Value;
            app.debuffChance = app.debuffChanceField.Value;
            app.defenceRed = app.defenceRedField.Value;
            app.pLevel = app.pLevelField.Value;
            app.eLevel = app.eLevelField.Value;
        end
        
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            hold(app.UIAxes,'on');
            legend(app.UIAxes,'on');
        end

        % Button pushed function: addButton
        function addButtonPushed(app, event)
            app = app.getAttributes();
            x = 0:0.1:1;
            legendName = app.attributeSelectDropDown.Value;
            switch app.attributeSelectDropDown.Value
                case '攻击增幅'
                    app.attackAmp = x;
                case '增伤'
                    app.damageAmp = x;
                case '暴击率'
                    app.criChance = x;
                case '暴击伤害'
                    app.criDamage = 1+x;
                case '韧性'
                    app.toughness = x;
                case '属性抗性'
                    app.damageResist = x;
                case '易伤'
                    app.vulnerable = x;
                case '穿透'
                    app.penetrate = x;
                case '命中'
                    app.debuffChance = x;
                case '减防'
                    app.defenceRed = x;
                otherwise
                    app.warningLabel.Text = ['不存在词条', app.attributeSelectDropDown.Value];
                    app.warningLabel.Visible = true;
                    return;
            end
            y = app.damageEstimation();
            if isKey(app.plots, legendName)
                app.warningLabel.Text = ['图像(', legendName, ')已存在，将替换为新图像'];
                app.warningLabel.Visible = true;
                delete(app.plots(legendName));
                textObjs = findobj(app.UIAxes, 'Tag', legendName, 'Type', 'text');
                if ~isempty(textObjs)
                    delete(textObjs);
                end
            else
                app.warningLabel.Visible = false;
            end
            p = plot(app.UIAxes, x, y, '-o','DisplayName',legendName);
            app.plots(legendName) = p;
            app.graphList.Items = keys(app.plots);
            h = gobjects(1, size(x,2));
            %adjust graph
            for i = 1:size(x,2)
                h(i) = text(app.UIAxes, x(i), y(i), ['(',num2str(x(i)),',',num2str(y(i)),')'], 'Color', get(p, 'Color'), 'Tag', legendName);
            end
            extS = get(h(1), 'Extent');
            extE = get(h(size(h, 2)), 'Extent');
            leftPosition = min(extS(1), extE(1));
            downPosition = min(extS(2), extE(2));
            rightPosition = max(extS(1)+extS(3), extE(1)+extE(3));
            upperPosition = max(extS(2)+extS(4), extE(2)+extE(4));
            xlim(app.UIAxes, [min(leftPosition, min(xlim(app.UIAxes))), max(rightPosition, max(xlim(app.UIAxes)))]);
            ylim(app.UIAxes, [min(downPosition, min(ylim(app.UIAxes))), max(upperPosition, max(ylim(app.UIAxes)))]);
            
        end

        % Button pushed function: deleteBtn
        function deleteBtnButtonPushed(app, event)
            if size(app.graphList.Value) ~= 0
                delete(app.plots(app.graphList.Value));
                textObjs = findobj(app.UIAxes, 'Tag', app.graphList.Value, 'Type', 'text');
                if ~isempty(textObjs)
                    delete(textObjs);
                end
                remove(app.plots, app.graphList.Value);
                app.graphList.Items = keys(app.plots);
            end
        end

        % Button pushed function: calButton
        function calButtonPushed(app, event)
            app = app.getAttributes();
            app.estResultField.Value = num2str(app.damageEstimation());
            app.criChance = 0;
            app.NcriResultField.Value = num2str(app.damageEstimation());
            app.criChance = 1;
            app.criResultField.Value = num2str(app.damageEstimation());
        end

        % Button pushed function: clearBtn
        function clearBtnButtonPushed(app, event)
            lineObjs = findobj(app.UIAxes, 'Type', 'line');
            if ~isempty(lineObjs)
                delete(lineObjs);
            end
            textObjs = findobj(app.UIAxes, 'Type', 'text');
            if ~isempty(textObjs)
                delete(textObjs);
            end
            app.plots = containers.Map;
            app.graphList.Items = {};
        end

        % Value changed function: Switch
        function SwitchValueChanged(app, event)
            value = app.Switch.Value;
            if value == "Off" % delete all text
                textObjs = findobj(app.UIAxes, 'Type', 'text');
                if ~isempty(textObjs)
                    delete(textObjs);
                end
            else
            end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = '崩坏：星穹铁道简易伤害计算器';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, '伤害图标')
            xlabel(app.UIAxes, '属性')
            ylabel(app.UIAxes, '伤害期望')
            app.addButton = uibutton(app.UIFigure, 'push');
            app.addButton.ButtonPushedFcn = createCallbackFcn(app, @addButtonPushed, true);
            app.addButton.Text = '添加';
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = '基础攻击';
            app.basicATKField = uieditfield(app.UIFigure, 'numeric');
            app.basicATKField.Limits = [0 Inf];
            app.basicATKField.Value = 1016;
            app.Label_3 = uilabel(app.UIFigure);
            app.Label_3.Text = '额外攻击';
            app.addATKField = uieditfield(app.UIFigure, 'numeric');
            app.addATKField.Limits = [0 Inf];
            app.addATKField.Value = 2337;
            app.Label_4 = uilabel(app.UIFigure);
            app.Label_4.Text = '技能倍率';
            app.amplitudeField = uieditfield(app.UIFigure, 'numeric');
            app.amplitudeField.Limits = [0 Inf];
            app.amplitudeField.Value = 0.9;
            app.Label_5 = uilabel(app.UIFigure);
            app.Label_5.Text = '增伤';
            app.damageAmpField = uieditfield(app.UIFigure, 'numeric');
            app.damageAmpField.Limits = [0 Inf];
            app.damageAmpField.Value = 0.1;
            app.Label_6 = uilabel(app.UIFigure);
            app.Label_6.Text = '暴击率';
            app.criChanceField = uieditfield(app.UIFigure, 'numeric');
            app.criChanceField.Limits = [0 1];
            app.criChanceField.Value = 0.78;
            app.Label_7 = uilabel(app.UIFigure);
            app.Label_7.Text = '暴击伤害';
            app.criDamageField = uieditfield(app.UIFigure, 'numeric');
            app.criDamageField.Limits = [0 Inf];
            app.criDamageField.Value = 1.072;
            app.Label_8 = uilabel(app.UIFigure);
            app.Label_8.Text = '韧性';
            app.toughnessField = uieditfield(app.UIFigure, 'numeric');
            app.toughnessField.Limits = [0 1];
            app.toughnessField.Value = 0.1;
            app.Label_9 = uilabel(app.UIFigure);
            app.Label_9.Text = '属性抗性';
            app.damageResistField = uieditfield(app.UIFigure, 'numeric');
            app.damageResistField.Limits = [-1 1];
            app.damageResistField.Value = 0.2;
            app.Label_10 = uilabel(app.UIFigure);
            app.Label_10.Text = '易伤';
            app.vulnerableField = uieditfield(app.UIFigure, 'numeric');
            app.vulnerableField.Limits = [0 10];
            app.Label_11 = uilabel(app.UIFigure);
            app.Label_11.Text = '穿透';
            app.penetrateField = uieditfield(app.UIFigure, 'numeric');
            app.penetrateField.Limits = [0 1];
            app.Label_12 = uilabel(app.UIFigure);
            app.Label_12.Text = '最终命中';
            app.debuffChanceField = uieditfield(app.UIFigure, 'numeric');
            app.debuffChanceField.Limits = [0 1];
            app.debuffChanceField.Value = 1;
            app.Label_13 = uilabel(app.UIFigure);
            app.Label_13.Text = '减防';
            app.defenceRedField = uieditfield(app.UIFigure, 'numeric');
            app.defenceRedField.Limits = [0 1];
            app.defenceRedField.Value = 0.1;
            app.Label_14 = uilabel(app.UIFigure);
            app.Label_14.Text = '我方等级';
            app.pLevelField = uieditfield(app.UIFigure, 'numeric');
            app.pLevelField.Limits = [1 80];
            app.pLevelField.Value = 70;
            app.Label_15 = uilabel(app.UIFigure);
            app.Label_15.Text = '敌方等级';
            app.eLevelField = uieditfield(app.UIFigure, 'numeric');
            app.eLevelField.Limits = [1 100];
            app.eLevelField.Value = 63;
            app.Label_16 = uilabel(app.UIFigure);
            app.Label_16.Text = '绘图属性';
            app.attributeSelectDropDown = uidropdown(app.UIFigure);
            app.attributeSelectDropDown.Items = {'攻击增幅', '增伤', '暴击率', '暴击伤害', '韧性', '属性抗性', '易伤', '穿透', '命中', '减防'};
            app.attributeSelectDropDown.Value = '攻击增幅';
            app.Label_17 = uilabel(app.UIFigure);
            app.Label_17.Text = '当前图像';
            app.graphList = uilistbox(app.UIFigure);
            app.graphList.Items = {};
            app.graphList.Value = {};
            app.deleteBtn = uibutton(app.UIFigure, 'push');
            app.deleteBtn.ButtonPushedFcn = createCallbackFcn(app, @deleteBtnButtonPushed, true);
            app.deleteBtn.Text = '删除';
            app.basicAttributesLabel = uilabel(app.UIFigure);
            app.basicAttributesLabel.Text = '基础属性：';
            app.commentLabel = uilabel(app.UIFigure);
            app.commentLabel.Text = {'默认数值为70级希儿装备叠五星海，'; '一般天才套攻击球遗器面板，'; '面对63级无弱点敌人'};
            app.warningLabel = uilabel(app.UIFigure);
            app.warningLabel.Visible = 'off';
            app.calButton = uibutton(app.UIFigure, 'push');
            app.calButton.ButtonPushedFcn = createCallbackFcn(app, @calButtonPushed, true);
            app.calButton.Text = '伤害计算';
            app.Label_18 = uilabel(app.UIFigure);
            app.Label_18.Text = '暴击伤害';
            app.criResultField = uieditfield(app.UIFigure, 'text');
            app.criResultField.Editable = 'off';
            app.Label_19 = uilabel(app.UIFigure);
            app.Label_19.Text = '非暴击伤害';
            app.NcriResultField = uieditfield(app.UIFigure, 'text');
            app.NcriResultField.Editable = 'off';
            app.Label_20 = uilabel(app.UIFigure);
            app.Label_20.Text = '期望伤害';
            app.estResultField = uieditfield(app.UIFigure, 'text');
            app.estResultField.Editable = 'off';
            app.clearBtn = uibutton(app.UIFigure, 'push');
            app.clearBtn.ButtonPushedFcn = createCallbackFcn(app, @clearBtnButtonPushed, true);
            app.clearBtn.Text = '清空';
            app.Label_21 = uilabel(app.UIFigure);
            app.Label_21.Text = '显示数据';
            app.Switch = uiswitch(app.UIFigure, 'slider');
            app.Switch.ValueChangedFcn = createCallbackFcn(app, @SwitchValueChanged, true);
            app.Switch.Value = 'On';
        end
    end

    methods (Access = public)

        % Construct app
        function app = app1

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end